classdef Drag2New < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DeleteItemButton           matlab.ui.control.Button
        SaveFileButton             matlab.ui.control.Button
        NewFilenameEditField       matlab.ui.control.EditField
        NewFilenameEditFieldLabel  matlab.ui.control.Label
        NewDatafileclickListBox    matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        Datafile % ImData array to export
    end
    
    methods (Access = public)
        
        function results = autoloadFGUI(app,filename)
                  if size(filename,1)>1
             replace=0
           else
               replace=1
           end
           if ~iscell(filename)
               filename={filename}
           end
         
        i=1
         if filename{i,1}(1,[end-4:end])==".FGUI"
             dlg = msgbox('Load operation in progress...','Saving file');
            data=load(filename{i,1}, '-mat' ) ;
            data=data.data2save;
            if isstruct(data)==1
            data=Old_ImData_2_New(data)   
            end
            if ishghandle(dlg)
                    delete(dlg);
            end
         elseif filename{i,1}(1,[end-3:end])==".mes"

            data=LoadMESfile1(filename{i,1});

         elseif filename{i,1}(1,[end-3:end])==".spc" | filename{i,1}(1,[end-3:end])==".SPC"
            
             if replace==0
                  progressbar(size(filename,1))
                for i=1:size(filename)
                progressbar(i/size(filename,1))
                Data_in(i,1)=ImData   
                isG=~isempty(strfind(filename{i,1},'_m1_'))
                if isG==1
                    channel=1
                    Import_FLIM_LS2(Data_in(i,1),filename{i,1},channel,3);
                    channel=2
                    filename2=strrep(filename{i,1},'_m1_','_m2_');
                    Import_FLIM_LS2(Data_in(i,1),filename2,channel,3);
                else
                    channel=2
                    Import_FLIM_LS2(Data_in(i,1),filename{i,1},channel,3);
                    channel=1
                    filename2=strrep(filename{i,1},'_m2_','_m1_');
                    Import_FLIM_LS2(Data_in(i,1),filename2,channel,3);
                end
                end

                
            app.Datafile=cat(1,app.Datafile,Data_in)
            app.Datafile.findComment(app.ListBox_2);  
            
                    return
            elseif replace==1

                data=app.Datafile(app.Data_Selection,1);
                if size(filename,1)==size(data,1)
                for i=1:size(filename)
                isG=~isempty(strfind(filename{i,1},'_m1_'))
                if isG==1
                    channel=1
                    Import_FLIM_LS2(data(i,1),filename{i,1},channel,3);
                    channel=2
                    filename2=strrep(filename{i,1},'_m1_','_m2_');
                    Import_FLIM_LS2(data(i,1),filename2,channel,3);
                else
                    channel=2
                    Import_FLIM_LS2(data(i,1),filename{i,1},channel,3);
                    channel=1
                    filename2=strrep(filename{i,1},'_m2_','_m1_');
                    Import_FLIM_LS2(data(i,1),filename2,channel,3);
                end
                end
                end


                app.importtype='Class';  
            app.Datafile.findComment(app.ListBox_2);  
            end
                    return
         elseif filename{i,1}(1,[end-4:end])==".mesc"
            data=LoadMESc(filename{i,1});
         else
            
         end
        

         data=arrayfun(@(x) FixSubtype(x),data,'UniformOutput',true)
         if isobject(data)
            app.Datafile=cat(1,app.Datafile,data);
            app.Datafile.findComment(app.NewDatafileclickListBox);  
            
        end
    end
        
        function results = Newdata(app,file)
            app.Datafile=cat(1,app.Datafile,file);
            app.Datafile.findComment(app.NewDatafileclickListBox);  
        end
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Datafile=ImData;
            app.Datafile.findComment(app.NewDatafileclickListBox);
            DnD_uifigure(app.NewDatafileclickListBox, @(o,dat)app.autoloadFGUI(dat.names));
        end

        % Button pushed function: DeleteItemButton, SaveFileButton
        function operations(app, event)
            if iscell(app.NewDatafileclickListBox.Value)==1
                selected_dataitems=cell2mat(app.NewDatafileclickListBox.Value)
            else
                selected_dataitems=app.NewDatafileclickListBox.Value
            end
            
            dataitems_index=1:1:size(app.NewDatafileclickListBox.Items,2)
            
            filename=sprintf("C:/Users/%s.FGUI",app.NewFilenameEditField.Value)
            if event.Source.Text=="Save File" 
                desc=inputdlg('Provide File Description','Description',[15 55],{app.NewFilenameEditField.Value});
                desc=cell2mat(desc)
%                 data2save=app.Datastore_class;
                data2save=arrayfun(@ImData_2_Struct,app.Datafile,'UniformOutput',true);
%                 data2save=arrayfun(@struct,app.Datastore_class,'UniformOutput',true);
                [filename, pathname, filterindex] = uiputfile(filename);
                savename=cat(2,pathname,filename);
                dlg = msgbox('Save operation in progress...','Saving file');
                save(savename, 'data2save', 'desc');        
                if ishghandle(dlg)
                    delete(dlg);
                end
            elseif event.Source.Text=="Delete Item"
                flag = ~ismember(dataitems_index,selected_dataitems);
                index = find(flag);
                app.Datafile=app.Datafile(index,1);    
            end

             app.Datafile.findComment(app.NewDatafileclickListBox);  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Tag = 'FGUItransfer';
            app.NewDatafileclickListBox = uilistbox(app.UIFigure);
            app.NewDatafileclickListBox.Multiselect = 'on';
            app.NewDatafileclickListBox.Value = {'Item 1'};
            app.NewFilenameEditFieldLabel = uilabel(app.UIFigure);
            app.NewFilenameEditFieldLabel.Text = 'New Filename ';
            app.NewFilenameEditField = uieditfield(app.UIFigure, 'text');
            app.SaveFileButton = uibutton(app.UIFigure, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @operations, true);
            app.SaveFileButton.Text = 'Save File';
            app.DeleteItemButton = uibutton(app.UIFigure, 'push');
            app.DeleteItemButton.ButtonPushedFcn = createCallbackFcn(app, @operations, true);
            app.DeleteItemButton.Text = 'Delete Item';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Drag2New

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end