classdef SampleDirectoryTree < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileDescriptionTextArea       matlab.ui.control.TextArea
        FileDescriptionTextAreaLabel  matlab.ui.control.Label
        WorkingLabel                  matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        Tree                          matlab.ui.container.Tree
        Node                          matlab.ui.container.TreeNode
        Node2                         matlab.ui.container.TreeNode
        Node3                         matlab.ui.container.TreeNode
        Node4                         matlab.ui.container.TreeNode
        BrowseButton                  matlab.ui.control.Button
        DirectoryEditField            matlab.ui.control.EditField
        DirectoryEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        AllElements       % Output of dir (except '.' and '..') on the selected file path
        selectedFileIdx   % Index of the selected file so that it can be executed on
        currentNode       % Currently selected node
    end
    
    methods (Access = private)
        
        function populate_file_directory(app, path)
            % Large directory structures take a while to open
            app.WorkingLabel.Visible = 'on';
            pause(0.1) % Got to be a better way to get the visibililty change to execute??
            
            % First grab all elements (subdirectories and files)
            allElements=dir(fullfile(path, '*.FGUI'));
            % Remove unnecessary '.' and '..' folders
            names={allElements(:).name}';
            eraseMe = ismember(names,{'.','..'});
            allElements(eraseMe) = [];
            names(eraseMe) = []; % Clean up 'names' for consistency
            
            % Initialize the tree
            t = app.Tree;
            t.Children(1).Children.delete; % Clear out old nodes
            [~, topName] = fileparts(path);
            t.Children(1).Text = topName;  % Set the first Node text
            
            % Find which elements of allElements share the same folder
            numElements = size(allElements, 1);
            f1 = repmat({allElements.folder}, numElements, 1);
            f2 = repmat({allElements.folder}', 1, numElements);
            sharesFolder = strcmp(f1,f2);
            % Consolidate sharesFolders down so that each column is a
            % directory. Num columns should equal num directories + 1 (the
            % top level directory)
            % Using cumsum() twice accounts for directories with only one file
            sharesFolder = cumsum(sharesFolder, 2);
            sharesFolder = cumsum(sharesFolder, 2);
            sharesFolder(sharesFolder~=1) = 0;
            sharesFolder(:, sum(sharesFolder,1) == 0) = [];
            
            % Use a cell array to store parent nodes to access later. Note
            % that you are storing handles rather than values (ie soft
            % copies instead of hard copies). This mean that the node that
            % you point to inside the cell array is the same object that
            % lives in the tree. So you can index into the cell array to add
            % children to that node.
            parentNodeCells = {t.Children(1)}; % Populate the first one
            parentNodeFullPath = {path}; % Save node paths to search on
            
            numFolders = size(sharesFolder, 2);
            
            % Process each element of allElements
            for i = 1:numFolders
                elementsThisFolder = find(sharesFolder(:,i));
                
                for j = 1:length(elementsThisFolder)
                    thisIdx = elementsThisFolder(j);
                    thisElement = allElements(thisIdx);
                    
                    % Find which node is this element's parent node (ie
                    % directory). We search on the the full path in order
                    % to account for duplicate directory names
%                     nodeIdx = find(strcmp(parentNodeFullPath, thisElement.folder));
                    nodeIdx = strcmp(parentNodeFullPath, thisElement.folder);
                    
                    % Create the new node inside the parent node
                    n = uitreenode(parentNodeCells{nodeIdx}); %#ok<FNDSB> 
                    n.Text = thisElement.name;
                    n.NodeData = thisElement;
                    
                    % Add it to our cell arrays if it is a directory
                    if thisElement.isdir
                        cellIdx = length(parentNodeCells) + 1;
                        parentNodeCells{cellIdx} = n;
                        parentNodeFullPath{cellIdx} = fullfile(thisElement.folder, n.Text);
                    end
                    
                end
            end
            
            % Put it back in the app
            app.Tree = t;
            app.Tree.expand;
            
            % Save off allElements
            app.AllElements = allElements;
            
            app.WorkingLabel.Visible = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            parentDirStr = uigetdir(pwd);
            if char(parentDirStr)
                app.DirectoryEditField.Value = parentDirStr;
                DirectoryEditFieldValueChanged(app)
            end
        end

        % Value changed function: DirectoryEditField
        function DirectoryEditFieldValueChanged(app, event)
            path = app.DirectoryEditField.Value;
            populate_file_directory(app, path);
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.currentNode = app.Tree.SelectedNodes;
                currNodeData = app.currentNode.NodeData;
                if ~currNodeData.isdir
                    pathStr = fullfile(currNodeData.folder, currNodeData.name);
                end
                try
                desc=load(pathStr,"-mat",'desc');
                app.FileDescriptionTextArea.Value=desc.desc;
                end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if ~isempty(app.currentNode) && ~isempty(app.currentNode.NodeData)
                currNodeData = app.currentNode.NodeData;
                if ~currNodeData.isdir
                    pathStr = fullfile(currNodeData.folder, currNodeData.name);
                   app.UIFigure.RunningAppInstance.UIFigure.Parent.Children(1).RunningAppInstance.FijiGUIUIFigure.RunningAppInstance.autoloadFGUI(pathStr)
            end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.DirectoryEditFieldLabel.Text = 'Directory ';
            app.DirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.DirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @DirectoryEditFieldValueChanged, true);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'rightarrow.png';
            app.Button.Text = '';
            app.WorkingLabel = uilabel(app.UIFigure);
            app.WorkingLabel.Visible = 'off';
            app.WorkingLabel.Text = 'Working...';
            app.FileDescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.FileDescriptionTextAreaLabel.Text = 'File Description';
            app.FileDescriptionTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SampleDirectoryTree

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end